classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        btn                          matlab.ui.control.Button
        RubikLocationEditFieldLabel  matlab.ui.control.Label
        loc                          matlab.ui.control.EditField
        cbori                        matlab.ui.control.CheckBox
        cbiso                        matlab.ui.control.CheckBox
        cbfin                        matlab.ui.control.CheckBox
        UIpanel                      matlab.ui.container.Panel
        ChooseFileButton             matlab.ui.control.Button
        hiddenData                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn
        function buttonCallbacks(app, event)
            dir = app.hiddenData.Text;
            isFinal = app.cbfin.Value == 1;
            isOri = app.cbori.Value == 1;
            isIso = app.cbiso.Value == 1;
            
            if dir == ""
                msgbox('Choose any file first');
                return;
            end
            if ~(isFinal || isOri || isIso)
                msgbox('Choose any option first');
                return;
            end
            
            processImage(dir, isOri, isIso, isFinal, app.UIpanel);
        end

        % Button pushed function: ChooseFileButton
        function chooseFile(app, event)
            [fileName, path] = uigetfile('*.jpg;*.png;*.bmp');
            figure(app.UIFigure);
            
            app.loc.Value = fileName;
            app.hiddenData.Text = fullfile(path, fileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btn = uibutton(app.UIFigure, 'push');
            app.btn.ButtonPushedFcn = createCallbackFcn(app, @buttonCallbacks, true);
            app.btn.Text = 'Process';
            app.RubikLocationEditFieldLabel = uilabel(app.UIFigure);
            app.RubikLocationEditFieldLabel.Text = 'Rubik Location';
            app.loc = uieditfield(app.UIFigure, 'text');
            app.loc.Editable = 'off';
            app.cbori = uicheckbox(app.UIFigure);
            app.cbori.Text = 'show original image';
            app.cbiso = uicheckbox(app.UIFigure);
            app.cbiso.Text = 'show isometric color';
            app.cbfin = uicheckbox(app.UIFigure);
            app.cbfin.Text = 'show final';
            app.UIpanel = uipanel(app.UIFigure);
            app.UIpanel.Title = 'Image';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFile, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.hiddenData = uilabel(app.UIFigure);
            app.hiddenData.Visible = 'off';
            app.hiddenData.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end